classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ClockInOutButton  matlab.ui.control.Button
        Switch            matlab.ui.control.Switch
        TimeInLabel       matlab.ui.control.Label
        TimeOutLabel      matlab.ui.control.Label
        Label             matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClockInOutButton
        function ClockInOutButtonPushed(app, event)
            global numClicks;
            global data;
            numClicks = numClicks + 1;
            
            command = "cat SignInSheet_* >> SignInSheet.txt";
            
            if mod(numClicks, 2) == 1
                tic;
                app.Label.Text = datestr(now);
                writetable(cell2table(cellstr(datestr(now))), 'SignInSheet_2.csv','WriteVariableNames',false);
                system(command);
                app.Label_2.Text = "Currently clocked in!";
            else
                time = toc;
                writetable(cell2table(cellstr(datestr(now))), 'SignInSheet_3.csv','WriteVariableNames',false);
                system(command);
                %writetable(cell2table(cellstr(datestr(now))), "SignInSheet");
                disp(time);
                app.Label_2.Text = datestr(now);
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            global numClicks;
            numClicks = 0;
            global data;
            data = readtable('SignInSheet.txt');
            app.ClockInOutButton.Visible = true;
            app.Switch.Visible = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ClockInOutButton = uibutton(app.UIFigure, 'push');
            app.ClockInOutButton.ButtonPushedFcn = createCallbackFcn(app, @ClockInOutButtonPushed, true);
            app.ClockInOutButton.Visible = 'off';
            app.ClockInOutButton.Text = 'Clock In/Out';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.TimeInLabel = uilabel(app.UIFigure);
            app.TimeInLabel.Text = 'Time In';
            app.TimeOutLabel = uilabel(app.UIFigure);
            app.TimeOutLabel.Text = 'Time Out';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end